classdef APP_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        POSICINENXSliderLabel      matlab.ui.control.Label
        POSICINENXSlider           matlab.ui.control.Slider
        CARGASpinnerLabel          matlab.ui.control.Label
        CARGASpinner               matlab.ui.control.Spinner
        PARTCULASPOSITIVASLabel    matlab.ui.control.Label
        PARTCULASNEGATIVASLabel    matlab.ui.control.Label
        CARGASpinner_2Label        matlab.ui.control.Label
        CARGASpinner_2             matlab.ui.control.Spinner
        POSICINENXSlider_2Label    matlab.ui.control.Label
        POSICINENXSlider_2         matlab.ui.control.Slider
        CenterPanel                matlab.ui.container.Panel
        NMERODECARGASSpinnerLabel  matlab.ui.control.Label
        NMERODECARGASSpinner       matlab.ui.control.Spinner
        STARTButton                matlab.ui.control.Button
        CARGASSIMTRICASLabel       matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
        RightPanel                 matlab.ui.container.Panel
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
            %Numero para flechas del plano
N=25;

%Limites
minX=-2; 
maxX=+2;
minY=-2; 
maxY=+2;

%Definicion del plano
x=linspace(minX,maxX,N);
y=linspace(minY,maxY,N);

%Creacion plano
[xG,yG]=meshgrid(x,y);

%componentes x, y
u = 0;
v = 0;

N_c = app.NMERODECARGASSpinner.Value;
xN = app.POSICINENXSlider_2.Value;
xP = app.POSICINENXSlider.Value;
Qn = app.CARGASpinner_2.Value;
Qp = app.CARGASpinner.Value;


for i=-N_c/2*0.2:0.2:N_c/2*.2-.1
    
    %Posiciones de las particulas
    yN=i; 
    yP=i;
    
    %Campo electrico
    d=0.2;
    kC = 8.987e9;
    Rx = xG - xN;
    Ry = yG - yN;
    R = sqrt(Rx.^2 + Ry.^2).^3;
    Ex = kC .* Qn .* Rx ./ R;
    Ey = kC .* Qn .* Ry ./ R;
    Rx = xG - xP;
    Ry = yG - yP;
    R = sqrt(Rx.^2 + Ry.^2).^3;
    Ex = Ex + kC .* Qp .* Rx ./ R;
    Ey = Ey + kC .* Qp .* Ry ./ R;
    E = sqrt(Ex.^2 + Ey.^2);
    
    u = u + Ex./E;
    v = v + Ey./E;

end 

xN = xN; 
xP = xP;

h=quiver(app.UIAxes,xG,yG,u,v,'autoscalefactor',0.6, 'color','w','linewidth',1.2);
app.UIAxes.Color = 'k'; 
%set(h,'Color', 'w')
axis(app.UIAxes,[-1.5 1.5 -1.5 1.5]);
axis(app.UIAxes,'equal');


for i = -N_c/2*.2:0.2:N_c/2*0.2-.1
    
    yN=i; 
    yP=i;
    
    %Generar carga negativa en la gráfica
    h=rectangle(app.UIAxes,'Position',[xN-d/2,yN-d/2,d,d],'curvature',[1 1]);
    set(h,'Facecolor',[0 0 1]);
    text(app.UIAxes,xN-.03,i+.05,'-','Color','white','FontSize',20);
    
    %Generar carga positiva en la gráfica
    h=rectangle(app.UIAxes,'Position',[xP-d/2,yP-d/2,d,d],'curvature',[1 1]);
    set(h,'Facecolor',[1 0 0]);
    text(app.UIAxes,xP-.03,i,'+','Color','white','FontSize',20);

end 
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {503, 503, 503};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {503, 503};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {209, '1x', 46};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.POSICINENXSliderLabel = uilabel(app.LeftPanel);
            app.POSICINENXSliderLabel.Text = 'POSICIÓN EN X';
            app.POSICINENXSlider = uislider(app.LeftPanel);
            app.POSICINENXSlider.Limits = [-1.3 1.3];
            app.POSICINENXSlider.Value = -0.5;
            app.CARGASpinnerLabel = uilabel(app.LeftPanel);
            app.CARGASpinnerLabel.Text = 'CARGA';
            app.CARGASpinner = uispinner(app.LeftPanel);
            app.CARGASpinner.Limits = [1 20];
            app.CARGASpinner.Value = 5;
            app.PARTCULASPOSITIVASLabel = uilabel(app.LeftPanel);
            app.PARTCULASPOSITIVASLabel.Text = {'PARTÍCULAS '; 'POSITIVAS'};
            app.PARTCULASNEGATIVASLabel = uilabel(app.LeftPanel);
            app.PARTCULASNEGATIVASLabel.Text = {'PARTÍCULAS '; 'NEGATIVAS'};
            app.CARGASpinner_2Label = uilabel(app.LeftPanel);
            app.CARGASpinner_2Label.Text = 'CARGA';
            app.CARGASpinner_2 = uispinner(app.LeftPanel);
            app.CARGASpinner_2.Limits = [-20 -1];
            app.CARGASpinner_2.Value = -5;
            app.POSICINENXSlider_2Label = uilabel(app.LeftPanel);
            app.POSICINENXSlider_2Label.Text = 'POSICIÓN EN X';
            app.POSICINENXSlider_2 = uislider(app.LeftPanel);
            app.POSICINENXSlider_2.Limits = [-1.3 1.3];
            app.POSICINENXSlider_2.Value = 0.5;
            app.CenterPanel = uipanel(app.GridLayout);
            app.NMERODECARGASSpinnerLabel = uilabel(app.CenterPanel);
            app.NMERODECARGASSpinnerLabel.Text = 'NÚMERO DE CARGAS';
            app.NMERODECARGASSpinner = uispinner(app.CenterPanel);
            app.NMERODECARGASSpinner.Limits = [1 10];
            app.NMERODECARGASSpinner.Value = 5;
            app.STARTButton = uibutton(app.CenterPanel, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.CARGASSIMTRICASLabel = uilabel(app.CenterPanel);
            app.CARGASSIMTRICASLabel.Text = 'CARGAS SIMÉTRICAS';
            app.UIAxes = uiaxes(app.CenterPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.RightPanel = uipanel(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end